classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TheNotoriousNGonLabel           matlab.ui.control.Label
        MassdistributionEditField       matlab.ui.control.EditField
        MassdistributionEditFieldLabel  matlab.ui.control.Label
        ModemakerEditField              matlab.ui.control.EditField
        ModemakerEditFieldLabel         matlab.ui.control.Label
        CentermassEditField             matlab.ui.control.NumericEditField
        CentermassEditFieldLabel        matlab.ui.control.Label
        ShowsparcityCheckBox            matlab.ui.control.CheckBox
        ShowplotCheckBox                matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        NumberofmassesEditField         matlab.ui.control.NumericEditField
        NumberofmassesEditFieldLabel    matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        M % Description
        show_sparcity % Description
        should_plot % Description
        gif_maker % Description
        m_c % Description
        mass % Description
        mode % Description
        file % Description
    end
    
    methods (Access = public)
        
        function results = wheelmaker(app)
% wheelmaker creates the stiffness matrix for the N-gon and then creates
% gifs for the different eigenmodes with varying configurations
%   The input format is 'wheelmaker(app.M,should_plot,show_sparcity,gif_maker,m_c,mass)'
%   'app.M' defines the number of masses on the rim ('integer')
%   'should_plot' says if the N-gon should be plotted ('true' or 'false')
%   'show_sparcity' says if we want to see the matrix structure ('true' or 'false')
%   'gif_maker' says which eigenmodes to create gifs for, the input format is '(1:20)','2' or '0' means don't create the gif
%   'm_c' is for changing the central mass ('integer')
%   'mass' is for the alternating layout e.g [1 2 3] alternates the masses
%   between 1, 2 and 3 ('vector')

 
close all
% app.M=5; should_plot=false; show_sparcity=false; gif_maker=1; m_c=1;
% major=10;
% minor=1;
R = app.M./(4*pi);
masses = zeros(app.M+1,1); % app.M+1 used to help with plotting
masses(1) = R; 
masses(app.M+1) = R+0i;
seq_period = length(app.mass);

% Adding points using roots of unity
for ii = 0:app.M-1
    masses(ii+1) = R*exp((2*ii*pi/app.M)*1i);
    % masses(ii+1) = R*(major*cos(2*ii*pi/app.M)+1i*minor*sin(2*ii*pi/app.M));
end

%% Plotting

if app.should_plot
    f1_name = sprintf('%d-Gon',app.M);
    f1 = figure('Name',f1_name);
    figure(f1);
    plot(masses,'Color','black','Marker','.','MarkerSize',20)
    hold on
    plot(0,0,'Color','black','Marker','.','MarkerSize',20)
    
    % Adding springs to visualise
    % if view_springs
        line([0 R], [0, 0], 'LineStyle','-','color','black')
        for ii = 2:app.M
            spring = [0 masses(ii)];
            plot(spring, '-','color','black')
        end
    % end
    xlabel('x')
    ylabel('y')
    axis([-R-1 R+1 -R-1 R+1])
    axis equal
    hold off
end


%% Finding unit vectors

% Finds the x and y coordinates from the complex numbers, takes off the end
% as extra was added to make plotting easier, can treat x,y as e_c_j
x = real(masses(1:end-1));
y = imag(masses(1:end-1));
norm = sqrt(x.*x+ y.*y);
x = x./norm;    y = y./norm;

% Finding e_i,j, e(1) = e_1_2 e(2) = e_2_3
e_x_ij = zeros(app.M, 1);   e_y_ij = zeros(app.M, 1);
for ii = 2:app.M
    e_x_ij(ii-1) = x(ii) - x(ii-1);
    e_y_ij(ii-1) = y(ii) - y(ii-1);
end
e_x_ij(end) = x(1) - x(end);
e_y_ij(end) = y(1) - y(end);

% Finding e_j,i, starting going from 1 -> app.M then 2 -> 1 ..
e_x_ji = zeros(app.M, 1);
e_y_ji = zeros(app.M, 1);
for ii = 2:app.M
    e_x_ji(ii) = x(ii-1) - x(ii);
    e_y_ji(ii) = y(ii-1) - y(ii);
end
e_x_ji(1) = x(end) - x(1);
e_y_ji(1) = y(end) - y(1);

% Normalising e
norm_ij = sqrt(e_x_ij.*e_x_ij+ e_y_ij.*e_y_ij);
norm_ji = sqrt(e_x_ji.*e_x_ji+ e_y_ji.*e_y_ji);

e_x_ij = e_x_ij./norm_ij; e_y_ij = e_y_ij./norm_ij;
e_x_ji = e_x_ji./norm_ji; e_y_ji = e_y_ji./norm_ji;


%% Forming the matrix

b = zeros(2*app.M+2);
k = ones(2*app.M+2,1);
k_c = ones(2*app.M+2,1);
% k = 1:2*app.M+2;
% How to fill matrix
% Top half of matrix

% Filling up xi/left side
b(1,app.M) = k(1)*e_x_ji(1)^2; % app.M from 1 -> app.M
b(app.M,1) = k(app.M)*e_x_ij(app.M)^2; % 1 from app.M -> 1
for col = 1:app.M 
    for row = 1:app.M
        if row == col
           b(row,col) = -k(row)*(e_x_ij(row))^2 - k(row)*(e_x_ji(row))^2 - k_c(row)*x(row)^2;
        elseif col == row+1
            b(row,col) = k(row)*e_x_ij(row)^2;
        elseif row == col+1
            b(row,col) = k(row)*e_x_ji(row)^2;
        end
        b(row,app.M+1) = k_c(row)*x(row)^2; 
    end
end

% Filling up eta/rightside [Change the k indicies]
b(1,2*app.M+1) = k(1)*e_y_ji(1)*e_x_ji(1); 
b(app.M,app.M+2) = k(app.M)*e_y_ij(app.M)*e_x_ij(app.M);
for col = 1:app.M 
    for row = 1:app.M
        if row == col
            b(row,app.M+1+col) = -k(row)*(e_y_ij(row))*e_x_ij(row) - k(row)*(e_y_ji(row))*e_x_ji(row) - k_c(row)*y(row)*x(row); %since we take the dot product then direction
        elseif col == row+1 % to the right of the middle
            b(row,app.M+1+col) = k(row)*e_y_ij(row)*e_x_ij(row);
        elseif row == col+1 % to the left of the middle
            b(row,app.M+1+col) = k(row)*e_y_ji(row)*e_x_ji(row);
        end
        b(row,2*app.M+2) = k_c(row)*y(row)*x(row);
    end
end

% Filling up xi_c row (row n+1)
for col = 1:app.M
    b(app.M+1,col) = k_c(app.M+1)*x(col)^2; %xi_c
    b(app.M+1,col+app.M+1) = k_c(app.M+1)*x(col)*y(col); %eta_c
    b(app.M+1,app.M+1) = b(app.M+1,app.M+1) - k_c(app.M+1)*x(col)^2; %sum
    b(app.M+1,2*app.M+2) = b(app.M+1,2*app.M+2) - k_c(app.M+1)*y(col)*x(col); %sum
end

% Bottom half of the matrix

% Filling up xi/left side (first row index is n+2)
b(app.M+2,app.M) = k(app.M+2)*e_y_ji(1)*e_x_ji(1); 
b(2*app.M+1,1) = k(2*app.M+1)*e_y_ij(app.M)*e_x_ij(app.M);
for col = 1:app.M 
    for row = 1:app.M
        if row == col
            b(row+app.M+1,col) = -k(row+app.M+1)*e_x_ij(row)*e_y_ij(row) - k(row+app.M+1)*e_x_ji(row)*e_y_ji(row) - k_c(row+app.M+1)*x(row)*y(row);
        elseif col == row+1
            b(row+app.M+1,col) = k(row+app.M+1)*e_x_ij(row)*e_y_ij(row);
        elseif row == col+1
            b(row+app.M+1,col) = k(row+app.M+1)*e_x_ji(row)*e_y_ji(row);
        end
        b(row+app.M+1,app.M+1) = k_c(row+app.M+1)*x(row)*y(row);
    end
end

% Filling up eta/rightside [Change the k indicies]
b(app.M+2,2*app.M+1) = k(app.M+2)*e_y_ji(1)^2; 
b(2*app.M+1,app.M+2) = k(2*app.M+1)*e_y_ij(app.M)^2;
for col = 1:app.M 
    for row = 1:app.M
        if row == col
            b(row+app.M+1,app.M+1+col) = -k(row+app.M+1)*(e_y_ij(row))*e_y_ij(row) - k(row+app.M+1)*(e_y_ji(row))*e_y_ji(row) - k_c(row+app.M+1)*y(row)*y(row); %since we take the dot product then direction
        elseif col == row+1 % to the right of the middle
            b(row+app.M+1,app.M+1+col) = k(row+app.M+1)*e_y_ij(row)*e_y_ij(row);
        elseif row == col+1 % to the left of the middle
            b(row+app.M+1,app.M+1+col) = k(row+app.M+1)*e_y_ji(row)*e_y_ji(row);
        end
        b(row+app.M+1,2*app.M+2) = k_c(row+app.M+1)*y(row)*y(row);
    end
end

% Filling up xi_c row (row n+1)
for col =1:app.M
    b(2*(app.M+1),col) = k_c(2*(app.M+1))*x(col)*y(col);
    b(2*(app.M+1),col+app.M+1) = k_c(2*(app.M+1))*y(col)^2;
    b(2*(app.M+1),app.M+1) = b(2*(app.M+1),app.M+1) - k_c(2*(app.M+1))*x(col)*y(col);
    b(2*(app.M+1),2*app.M+2) = b(2*(app.M+1),2*app.M+2) - k_c(2*(app.M+1))*y(col)^2;
end

% Changing masses
b(app.M+1:app.M+1:2*app.M+2,:) = b(app.M+1:app.M+1:2*app.M+2,:)./app.m_c;
temp_b = [b(1:app.M,:) ; b(app.M+2:2*app.M+1,:)];

for ii = 1:seq_period
    temp_b(ii:seq_period:2*app.M,:) = temp_b(ii:seq_period:2*app.M,:)./app.mass(ii);
end
b(1:app.M,:) = temp_b(1:app.M,:);
b(app.M+2:2*app.M+1,:) = temp_b(app.M+1:2*app.M,:);

if app.show_sparcity
    f2 = figure('Name','Matrix Sparcity');
    figure(f2);
    spy(b);
end


%% app.Making the masses wiggle
[v,eigvals] = eig(b);
omega = real(sqrt(-eigvals));
[omega, idx] = sort(omega);
v2 = zeros(length(v));
for ii=1:length(v)
    v2(:,idx(ii,1)) = v(:,ii);
end
if app.gif_maker ~= 0
    f3 = figure('Name','Displacements');
    mass_string = num2str(app.mass);
    folder = sprintf("%d-Gon, central mass=%d, mass distribution=[%s]",app.M,app.m_c,mass_string);
    mkdir(folder);
    oldfolder = cd(folder);
    x = x.*norm; y=y.*norm;
    initpos = [x; 0; y; 0];
    T=20;
    figure(f3)
    for w = app.gif_maker
        % sprintf(w)
        m=1;
        for j=1:T
            % Finds the different modes
            eigmodet = real(exp(1i.*2.*j.*pi./T).*v);
            Newpos = eigmodet(:,w)+initpos;
            Mode = reshape(Newpos,app.M+1,2);
            center = Mode(app.M+1,:);
            Mode(app.M+1,:) = Mode(1,:);
            % Plots the different modes
            plot(Mode(:,1),Mode(:,2),'Color','black','Marker','.','MarkerSize',10);
            hold on;
            plot(center(1),center(2),'Color','black','Marker','.','MarkerSize',10);
            for ii = 1:app.M
                p1 = [center(1) Mode(ii,1)];
                p2 = [center(2) Mode(ii,2)];
                plot(p1, p2, '-','color','black');
            end
            axis equal
            axis([-R-1 R+1 -R-1 R+1]);
            titlename = sprintf('Mode %d',w);
            title(titlename);
            % Creates the movie files to be saved to the gif
            mov(m,w)=getframe;
            m = m+1;
            hold off
        end
        % Creates the gif files
        for ii = 1:length(mov(:,w))
            im{ii} = frame2im(mov(ii,w));
        end
        filename = sprintf("eigenmode%d.gif",w);
        filename_2 = sprintf("final_eigenmode%d.jpeg",w);
        for ii = 1:length(mov(:,w))
            [P,map] = rgb2ind(im{ii},256);
            if ii == 1
                imwrite(P,map,filename,"gif","LoopCount",Inf,"DelayTime",0.1);
            else
                imwrite(P,map,filename,"gif","WriteMode","append","DelayTime",0.1);
            end
        end
        imwrite(P,map,filename_2,"jpeg");
    end
    % Goes back to the original folder
    % imshow(filename,app.UIAxes);
    cd(oldfolder)
end
assignin('base','mov',mov);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumberofmassesEditField
        function NumberofmassesEditFieldValueChanged(app, event)
            app.M = app.NumberofmassesEditField.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            wheelmaker(app)
        end

        % Value changed function: CentermassEditField
        function CentermassEditFieldValueChanged(app, event)
            app.m_c = app.CentermassEditField.Value;
            
        end

        % Value changed function: ShowsparcityCheckBox
        function ShowsparcityCheckBoxValueChanged(app, event)
            app.show_sparcity = app.ShowsparcityCheckBox.Value;
            
        end

        % Value changed function: ShowplotCheckBox
        function ShowplotCheckBoxValueChanged(app, event)
            app.should_plot = app.ShowplotCheckBox.Value;
            
        end

        % Value changed function: MassdistributionEditField
        function MassdistributionEditFieldValueChanged(app, event)
            app.mass = str2num(app.MassdistributionEditField.Value);
            
        end

        % Value changed function: ModemakerEditField
        function ModemakerEditFieldValueChanged(app, event)
            app.gif_maker = str2num(app.ModemakerEditField.Value);

        end

        % Callback function
        function ModeEditFieldValueChanged(app, event)
            app.mode = app.ModeEditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {394, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NumberofmassesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofmassesEditFieldLabel.Text = 'Number of masses';
            app.NumberofmassesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofmassesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofmassesEditFieldValueChanged, true);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ShowplotCheckBox = uicheckbox(app.LeftPanel);
            app.ShowplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowplotCheckBoxValueChanged, true);
            app.ShowplotCheckBox.Text = 'Show plot';
            app.ShowsparcityCheckBox = uicheckbox(app.LeftPanel);
            app.ShowsparcityCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowsparcityCheckBoxValueChanged, true);
            app.ShowsparcityCheckBox.Text = 'Show sparcity';
            app.CentermassEditFieldLabel = uilabel(app.LeftPanel);
            app.CentermassEditFieldLabel.Text = 'Center mass';
            app.CentermassEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CentermassEditField.ValueChangedFcn = createCallbackFcn(app, @CentermassEditFieldValueChanged, true);
            app.ModemakerEditFieldLabel = uilabel(app.LeftPanel);
            app.ModemakerEditFieldLabel.Text = 'Mode maker';
            app.ModemakerEditField = uieditfield(app.LeftPanel, 'text');
            app.ModemakerEditField.ValueChangedFcn = createCallbackFcn(app, @ModemakerEditFieldValueChanged, true);
            app.ModemakerEditField.Placeholder = '1:10';
            app.MassdistributionEditFieldLabel = uilabel(app.LeftPanel);
            app.MassdistributionEditFieldLabel.Text = 'Mass distribution';
            app.MassdistributionEditField = uieditfield(app.LeftPanel, 'text');
            app.MassdistributionEditField.ValueChangedFcn = createCallbackFcn(app, @MassdistributionEditFieldValueChanged, true);
            app.MassdistributionEditField.Placeholder = '[1]';
            app.TheNotoriousNGonLabel = uilabel(app.LeftPanel);
            app.TheNotoriousNGonLabel.Text = 'The Notorious N-Gon!';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, '20-gon 2 rims', 'eigenmode30.gif');
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '20-Gon, central mass=10, mass distribution=[1  4  2]', 'eigenmode15.gif');
            app.Image3 = uiimage(app.RightPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '20-Gon, central mass=10, mass distribution=[1  4  2]', 'eigenmode12.gif');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end