classdef wheelmakerapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DTab_2                          matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        DisplaymodesPanel               matlab.ui.container.Panel
        DisplaycsvButton                matlab.ui.control.Button
        ModesListBox                    matlab.ui.control.ListBox
        ModesListBoxLabel               matlab.ui.control.Label
        DisplayGiforImageButton         matlab.ui.control.Button
        FinaloptionsPanel               matlab.ui.container.Panel
        ModestoplotholdctrlorshifttoselectmultipleListBox  matlab.ui.control.ListBox
        ModestoplotholdctrlorshifttoselectmultipleLabel  matlab.ui.control.Label
        CreatemodesButton               matlab.ui.control.Button
        MakeDirectory                   matlab.ui.control.Button
        CreatedirectoryEditField        matlab.ui.control.EditField
        CreatedirectoryEditFieldLabel   matlab.ui.control.Label
        SelectdirectoryDropDown         matlab.ui.control.DropDown
        SelectdirectoryDropDownLabel    matlab.ui.control.Label
        ViewsparsityButton              matlab.ui.control.Button
        MassconnectionsPanel            matlab.ui.container.Panel
        position_label                  matlab.ui.control.Label
        UpdatemassvalueButton           matlab.ui.control.Button
        CurrentmassvalueLabel           matlab.ui.control.Label
        NewmassvalueEditField           matlab.ui.control.NumericEditField
        NewmassvalueEditFieldLabel      matlab.ui.control.Label
        RemovemassButton                matlab.ui.control.Button
        UpdatepositionButton            matlab.ui.control.Button
        ChangemasspositionEditField     matlab.ui.control.EditField
        ChangemasspositionEditFieldLabel  matlab.ui.control.Label
        AddmassButton                   matlab.ui.control.Button
        MatrixcreatedLabel              matlab.ui.control.Label
        SetconnectionsButton            matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        ViewconfigButton                matlab.ui.control.Button
        newConnections                  matlab.ui.control.EditField
        NewconnectionsLabel             matlab.ui.control.Label
        AddorremoveconnectionscommaseperatedLabel  matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        currentConnections              matlab.ui.control.Label
        SelectamassDropDown             matlab.ui.control.DropDown
        SelectamassDropDownLabel        matlab.ui.control.Label
        MassconfiguratorPanel           matlab.ui.container.Panel
        LoadconfigurationButton         matlab.ui.control.Button
        SelectdirectoryDropDown_2       matlab.ui.control.DropDown
        SelectdirectoryDropDown_2Label  matlab.ui.control.Label
        ConfigurationButtonGroup        matlab.ui.container.ButtonGroup
        DrawButton                      matlab.ui.control.RadioButton
        ImportButton                    matlab.ui.control.RadioButton
        ChebyButton                     matlab.ui.control.RadioButton
        StandardButton                  matlab.ui.control.RadioButton
        plotStandard                    matlab.ui.control.Button
        rimsStandard                    matlab.ui.control.NumericEditField
        rimsStandardLabel               matlab.ui.control.Label
        massesStandard                  matlab.ui.control.NumericEditField
        massesStandardLabel             matlab.ui.control.Label
        DTab_3                          matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        DisplaymodesPanel_2             matlab.ui.container.Panel
        DisplaycsvButton_2              matlab.ui.control.Button
        ModesListBox_2                  matlab.ui.control.ListBox
        ModesListBox_2Label             matlab.ui.control.Label
        DisplayGiforImageButton_2       matlab.ui.control.Button
        FinaloptionsPanel_2             matlab.ui.container.Panel
        ModestoplotholdctrlorshifttoselectmultipleListBox_2  matlab.ui.control.ListBox
        ModestoplotholdctrlorshifttoselectmultipleListBox_2Label  matlab.ui.control.Label
        CreatemodesButton_2             matlab.ui.control.Button
        MakeDirectory_2                 matlab.ui.control.Button
        CreatedirectoryEditField_2      matlab.ui.control.EditField
        CreatedirectoryEditField_2Label  matlab.ui.control.Label
        SelectdirectoryDropDown_4       matlab.ui.control.DropDown
        SelectdirectoryDropDown_4Label  matlab.ui.control.Label
        ViewsparsityButton_2            matlab.ui.control.Button
        MassconnectionsPanel_2          matlab.ui.container.Panel
        position_label_2                matlab.ui.control.Label
        UpdatemassvalueButton_2         matlab.ui.control.Button
        CurrentmassvalueLabel_2         matlab.ui.control.Label
        NewmassvalueEditField_2         matlab.ui.control.NumericEditField
        NewmassvalueEditField_2Label    matlab.ui.control.Label
        RemovemassButton_2              matlab.ui.control.Button
        UpdatepositionButton_2          matlab.ui.control.Button
        ChangemasspositionEditField_2   matlab.ui.control.EditField
        ChangemasspositionEditField_2Label  matlab.ui.control.Label
        AddmassButton_2                 matlab.ui.control.Button
        MatrixcreatedLabel_2            matlab.ui.control.Label
        SetconnectionsButton_2          matlab.ui.control.Button
        RemoveButton_2                  matlab.ui.control.Button
        ViewconfigButton_2              matlab.ui.control.Button
        newConnections_2                matlab.ui.control.EditField
        NewconnectionsLabel_2           matlab.ui.control.Label
        AddorremoveconnectionscommaseperatedLabel_2  matlab.ui.control.Label
        ConnectButton_2                 matlab.ui.control.Button
        currentConnections_2            matlab.ui.control.Label
        SelectamassDropDown_2           matlab.ui.control.DropDown
        SelectamassDropDown_2Label      matlab.ui.control.Label
        MassconfiguratorPanel_2         matlab.ui.container.Panel
        ConfigurationButtonGroup_2      matlab.ui.container.ButtonGroup
        CustomButton                    matlab.ui.control.RadioButton
        ImportButton_2                  matlab.ui.control.RadioButton
        OctahedronButton                matlab.ui.control.RadioButton
        CubeButton                      matlab.ui.control.RadioButton
        TetrahedronButton               matlab.ui.control.RadioButton
        LoadconfigurationButton_2       matlab.ui.control.Button
        SelectdirectoryDropDown_3       matlab.ui.control.DropDown
        SelectdirectoryDropDown_3Label  matlab.ui.control.Label
        plotStandard_2                  matlab.ui.control.Button
        massesStandard_2                matlab.ui.control.NumericEditField
        massesStandardLabel_2           matlab.ui.control.Label
    end

    
    properties (Access = private)
        rims % number of rims
        mass % Holds data for all mass objects
        N % Number of masses (will be the central)
        R % Holds axis data
        M % How many masses
        rim_no % which rim we are on
        m1 % Mass that's selected
        b % the stiffness matrix
        GifApp % External app for viewing modes
        faces
        vertices
    end
    
    methods (Access = private)
        
        function show_cheby(app)
            % Shows the settings for cheby mode
            app.rimsStandard.Visible = 'on';
            app.plotStandard.Visible = 'on';
            app.rimsStandardLabel.Visible = 'on';
        end
        
        function show_standard(app)
            % Shows the settings for standard mode
            app.rimsStandard.Visible = 'on';
            app.massesStandard.Visible = 'on';
            app.plotStandard.Visible = 'on';
            app.rimsStandardLabel.Visible = 'on';
            app.massesStandardLabel.Visible = 'on';
        end
        
        function show_import(app)
            % Shows settings for import mode
            app.LoadconfigurationButton.Visible = 'on';
            app.SelectdirectoryDropDown_2.Visible = 'on';
            app.SelectdirectoryDropDown_2Label.Visible = 'on';
            
        end
        
        function show_draw(app)
            % Shows the settings for draw mode
            app.massesStandard.Visible = 'on';
            app.massesStandardLabel.Visible = 'on';
            app.plotStandard.Visible = 'on';
        end
        
        function reset_screen(app)
            % Resets the screen
            app.rimsStandard.Visible = 'off';
            app.massesStandard.Visible = 'off';
            app.plotStandard.Visible = 'off';
            app.rimsStandardLabel.Visible = 'off';
            app.massesStandardLabel.Visible = 'off';
            app.MatrixcreatedLabel.Visible = 'off';
            app.LoadconfigurationButton.Visible = 'off';
            app.SelectdirectoryDropDown_2.Visible = 'off';
            app.SelectdirectoryDropDown_2Label.Visible = 'off';
        end
        
        function create_config(app)
            % Runs when the config button is pressed, draws from functions
            % for the configuration
            selectedButton = app.ConfigurationButtonGroup.SelectedObject.Text;
            switch selectedButton
                case 'Standard'
                    [app.rims,app.mass,app.N,app.R] = standard_config(app.M,app.rim_no);
                case 'Cheby'
                    [app.rims,app.mass,app.N,app.R] = cheby_config(app.rim_no);
                case 'Draw'
                    msg = sprintf('Please select locations for %i masses.', app.M);
                    uiwait(msgbox(msg,'Draw Info','modal'))
                    [app.R,app.mass,app.rims,app.N,app.rim_no] = pictureConfig(app.M);
                case 'Import'
                    show_import(app)
            end
        end
        
        function reset_connections_panel(app)
            app.MatrixcreatedLabel.Visible = 'off';
            app.currentConnections.Text = '';
            app.newConnections.Value = '';
            app.SelectamassDropDown.Items = {'Select a mass'};
        end
        
        function update_mass_selector(app)
            app.SelectamassDropDown.Items = {''};
            for ii = 1:app.N
                massallocator(ii) = sprintf("%i",ii);
            end
            app.SelectamassDropDown.Items = [app.SelectamassDropDown.Items, massallocator];
        end
        
        function mass_connected_text(app)
            % Update connections text
            g=sprintf('%d ', app.mass(app.m1).connections);
            txt = sprintf("mass %i is currently connected to:\n %s",app.m1,g);
            app.currentConnections.Text = txt;
        end
        
        function remove_matrix_label(app)
            app.MatrixcreatedLabel.Visible = 'off';
        end
        
        function update_mass_value_label(app)
            % Update mass value label
            mass_value_txt = sprintf("mass %i's value is %i",app.m1,app.mass(app.m1).mass_value);
            app.CurrentmassvalueLabel.Text = mass_value_txt;
        end
        
        function update_directory(app,~)
            a = dir;
            t={''};
            count = 1;
            for j = 3:length(a)
            if a(j).isdir == 1
                temp = a(j).name;
                t(count) = {temp};
                count = count + 1;
            end
            end
            % if threeD
                app.SelectdirectoryDropDown_3.Items = t;
                app.SelectdirectoryDropDown_4.Items = t;
            % else
                app.SelectdirectoryDropDown.Items = t;
                app.SelectdirectoryDropDown_2.Items = t;
            % end
        end
   
        
        function update_mass_position_label(app, m1)
            try
                txt = sprintf("(%1.2f, %1.2f)",round(app.mass(m1).position_x,2),round(app.mass(m1).position_y,2));
                app.position_label.Text = txt;
            end
            
        end
        
        %%%%%%% 3D functions

        function create_config3(app)
            % Runs when the config button is pressed, draws from functions
            % for the configuration
           
            selectedButton = app.ConfigurationButtonGroup_2.SelectedObject.Text;
            switch selectedButton
                case 'Octahedron'
                    [app.faces,app.R,app.mass,app.rims,app.N,app.rim_no] = octahedron;
                case 'Cube'
                    [app.faces,app.R,app.mass,app.rims,app.N,app.rim_no]  = cube;
                case 'Tetrahedron'
                    [app.faces,app.R,app.mass,app.rims,app.N,app.rim_no] = tetrahedron;
                case 'Import'
                    show_import3(app)
                case 'Custom'
                    [app.R,app.mass,app.rims,app.N,app.rim_no] = custom3(app.massesStandard_2.Value);
            end
        end

        function update_mass_selector3(app)
            app.SelectamassDropDown_2.Items = {''};
            for ii = 1:app.N
                massallocator(ii) = sprintf("%i",ii);
            end
            app.SelectamassDropDown_2.Items = [app.SelectamassDropDown_2.Items, massallocator];
        end

        function reset_connections_panel3(app)
            app.MatrixcreatedLabel_2.Visible = 'off';
            app.currentConnections_2.Text = '';
            app.newConnections_2.Value = '';
            app.SelectamassDropDown_2.Items = {'Select a mass'};
        end
        
        function mass_connected_text3(app)
            g=sprintf('%d ', app.mass(app.m1).connections);
            txt = sprintf("mass %i is currently connected to:\n %s",app.m1,g);
            app.currentConnections_2.Text = txt;
        end

        function update_mass_value_label3(app)
            mass_value_txt = sprintf("mass %i's value is %i",app.m1,app.mass(app.m1).mass_value);
            app.CurrentmassvalueLabel_2.Text = mass_value_txt;
        end

        function update_mass_position_label3(app, m1)
            try
                txt = sprintf("(%1.2f, %1.2f, %1.2f)",round(app.mass(m1).position_x,2),round(app.mass(m1).position_y,2),round(app.mass(m1).position_z,2));
                app.position_label_2.Text = txt;
            end
        end
        
        function remove_matrix_label3(app)
            app.MatrixcreatedLabel_2.Visible = 'off';
        end
        
        function reset_screen3(app)
            % Resets the screen
            app.massesStandard_2.Visible = 'off';
            app.plotStandard_2.Visible = 'off';
            app.massesStandardLabel_2.Visible = 'off';
            app.MatrixcreatedLabel_2.Visible = 'off';
            app.LoadconfigurationButton_2.Visible = 'off';
            app.SelectdirectoryDropDown_3.Visible = 'off';
            app.SelectdirectoryDropDown_3Label.Visible = 'off';
        end
        
        function show_standard3(app)
            % app.massesStandard_2.Visible = 'on';
            app.plotStandard_2.Visible = 'on';
            % app.massesStandardLabel_2.Visible = 'on';
        end
        
        function show_import3(app)
            % Shows settings for import mode
            app.LoadconfigurationButton_2.Visible = 'on';
            app.SelectdirectoryDropDown_3.Visible = 'on';
            app.SelectdirectoryDropDown_3Label.Visible = 'on';
        end
        
        function show_custom3(app)
            app.massesStandard_2.Visible = 'on';
            app.plotStandard_2.Visible = 'on';
            app.massesStandardLabel_2.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_directory(app,true);
            update_directory(app,false);
            show_standard(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            app.PlotconfigurationDropDownLabel.Visible = 'on';
            app.PlotconfigurationDropDown.Visible = 'on';
        end

        % Callback function
        function PlotconfigurationDropDownValueChanged(app, event)
            remove_matrix_label(app);
            value = app.PlotconfigurationDropDown.Value;
            if strcmp(value,'standard')
                app.MassconfiguratorPanel.Visible = 'on';
            end
        end

        % Button pushed function: plotStandard
        function plotStandardButtonPushed(app, event)
            reset_connections_panel(app)
            app.M = app.massesStandard.Value;
            app.rim_no = app.rimsStandard.Value;
            app.MassconnectionsPanel.Visible = 'on';
            create_config(app)
            update_mass_selector(app);
        end

        % Value changed function: SelectamassDropDown
        function SelectamassDropDownValueChanged(app, event)
            app.m1 = str2num(app.SelectamassDropDown.Value);
            createPlot(app.R,app.N,app.mass,app.m1);

            mass_connected_text(app)

            update_mass_value_label(app)

            update_mass_position_label(app, app.m1)
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            remove_matrix_label(app);
            m2 = str2double(strsplit(app.newConnections.Value, ','));
            m2 = m2(m2 <= app.N);
            app.mass = addConnection(app.mass, app.m1, m2);

            mass_connected_text(app)

            createPlot(app.R,app.N,app.mass,app.m1);
            app.newConnections.Value = '';
        end

        % Button pushed function: ViewconfigButton
        function ViewconfigButtonPushed(app, event)
            createPlot(app.R,app.N,app.mass,1:app.N);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            remove_matrix_label(app);
            m2 = str2double(strsplit(app.newConnections.Value, ','));
            app.mass = removeConnections(app.mass, app.m1, m2);

            % 
            mass_connected_text(app)

            createPlot(app.R,app.N,app.mass,app.m1);
            app.newConnections.Value = '';
        end

        % Button pushed function: SetconnectionsButton
        function SetconnectionsButtonPushed(app, event)
            app.b = [[bxx(app.N,app.mass) ; bxy(app.N,app.mass)], ...
    [bxy(app.N,app.mass) ; byy(app.N,app.mass)]];
            app.b(isnan(app.b))=0;


            app.MatrixcreatedLabel.Visible = 'on';
            app.FinaloptionsPanel.Visible = 'on';

            % Change mass values
            % cases
            if length(app.rims) == 1
                for i=1:app.N
                    % do first 1-N
                    app.b(i,:) = app.b(i,:) ./ app.mass(i).mass_value;
                    % do next N+1-2N+2
                    app.b(i+app.N,:) = app.b(i+app.N,:) ./ app.mass(i).mass_value;
                end
            elseif length(app.rims) > 1
                for j=1:length(app.rims)
                    for i=app.rims(j).starting_mass:app.rims(j).ending_mass
                        app.b(i,:) = app.b(i,:) ./ app.mass(i).mass_value;
                        app.b(i+app.N,:) = app.b(i+app.N,:) ./ app.mass(i).mass_value;
                    end
                end
                app.b(app.N,:) = app.b(app.N,:) ./ app.mass(app.N).mass_value;
                app.b(2*app.N,:) = app.b(2*app.N,:) ./ app.mass(app.N).mass_value;
            end

            items = "";
            for i = 1:length(app.b)
                % txt = 
                items(i) = sprintf("Mode %i",i);
            end

            app.ModestoplotholdctrlorshifttoselectmultipleListBox.Items = items;
            
        end

        % Button pushed function: ViewsparsityButton
        function ViewsparsityButtonPushed(app, event)
            f2 = figure("Name","Matrix sparcity diagram");
            spy(app.b);
        end

        % Drop down opening function: SelectdirectoryDropDown
        function SelectdirectoryDropDownOpening(app, event)
            a = dir(app.SelectdirectoryDropDown.Value);
            t={''};
            count = 1;
            for j = 3:length(a)
            % if a(j).isdir == 1
                temp = a(j).name;
                t(count) = {temp};
                count = count + 1;
            % end
            end
            app.ModesListBox.Items = t;
        end

        % Button pushed function: MakeDirectory
        function MakeDirectoryButtonPushed(app, event)
            if app.CreatedirectoryEditField.Value
                val = app.CreatedirectoryEditField.Value;
                mkdir(val);
                update_directory(app,false);
                app.SelectdirectoryDropDown.Value = val;
                SelectdirectoryDropDownOpening(app)
            end
            % startupFcn(app);
            

        end

        % Button pushed function: CreatemodesButton
        function CreatemodesButtonPushed(app, event)
            % Get modes that the user wants to plot
            % This is a cell array
            
            listBoxItems = app.ModestoplotholdctrlorshifttoselectmultipleListBox.Value;
            modes = zeros(1,length(listBoxItems));
            for i = 1:length(listBoxItems)
                % Split each cell and take integer of what's after the
                % space (i.e mode number)
                temp = strsplit(string(listBoxItems(i)));
                modes(i) = str2double(temp(2));
            end
            
            % Plot and animate modes
            mode_maker(app.SelectdirectoryDropDown.Value,'n','springs',app.b, ...
                app.mass,app.rim_no,app.R,modes);
            app.DisplaymodesPanel.Visible = 'on';
            % for j = 1:length(app.b)
            %     items(j) = {sprintf("%i",j)};
            % end
            
            % Update dropdown
            SelectdirectoryDropDownOpening(app, event)

            % Save configurationzx
            mass = app.mass; rims = app.rims; N = app.N; R = app.R; rim_no = app.rim_no;
            save_string = sprintf('%s/configuration.mat',app.SelectdirectoryDropDown.Value);
            save(save_string, "mass", "rims", "N", "R", "rim_no"); 

        end

        % Button pushed function: DisplayGiforImageButton
        function DisplayGiforImageButtonPushed(app, event)
            currentdir = cd;
            wd = sprintf("%s\\%s\\%s",currentdir,app.SelectdirectoryDropDown.Value,app.ModesListBox.Value);
            ModeDisplay(wd, app.ModesListBox.Value(1:end-4));
        end

        % Drop down opening function: SelectamassDropDown
        function SelectamassDropDownOpening(app, event)
            
        end

        % Selection changed function: ConfigurationButtonGroup
        function ConfigurationButtonGroupSelectionChanged(app, event)
            % Select configuration
            reset_screen(app)
            reset_connections_panel(app)
            selectedButton = app.ConfigurationButtonGroup.SelectedObject.Text;
            switch selectedButton
                case 'Standard'
                    show_standard(app)
                case 'Cheby'
                    show_cheby(app)
                case 'Draw'
                    show_draw(app)
                case 'Import'
                    show_import(app)
            end
            remove_matrix_label(app);
            
        end

        % Button pushed function: AddmassButton
        function AddmassButtonPushed(app, event)
            % Adds a mass
            [app.N, app.mass] = add_mass(app.N,app.mass,false);
            % Updates the selector
            update_mass_selector(app);
            % Update connections text
            mass_connected_text(app)
                
            createPlot(app.R,app.N,app.mass,1:app.N);
            remove_matrix_label(app);

        end

        % Button pushed function: UpdatepositionButton
        function UpdatepositionButtonPushed(app, event)
            % Change the position
            % Splits the input into x and y
            xyBoxValue = strsplit(app.ChangemasspositionEditField.Value,',');
            x = str2double(xyBoxValue(1));
            y = str2double(xyBoxValue(2));
            
            % Fix if we stretch out
            if x > app.R
                app.R = x + 0.5;
            end

            if y > app.R
                app.R = y + 0.5;
            end

            % Changes the positions
            app.mass = change_mass_position(app.m1,app.mass,x,y);
            createPlot(app.R,app.N,app.mass,1:app.N);

            app.ChangemasspositionEditField.Value = '';
            remove_matrix_label(app);

        end

        % Button pushed function: RemovemassButton
        function RemovemassButtonPushed(app, event)
            % Removes the mass
            app.mass = remove_mass(app.mass,app.m1,false);
            % Updates the selector, maybe change
            update_mass_selector(app);
            mass_connected_text(app);

            createPlot(app.R,app.N,app.mass,1:app.N);
            remove_matrix_label(app);
        end

        % Value changed function: NewmassvalueEditField
        function NewmassvalueEditFieldValueChanged(app, event)
       
            
        end

        % Button pushed function: UpdatemassvalueButton
        function UpdatemassvalueButtonPushed(app, event)
        mass_value = app.NewmassvalueEditField.Value;
        if mass_value ~= 0
            app.mass(app.m1).mass_value = mass_value;
            app.NewmassvalueEditField.Value = 0;
            remove_matrix_label(app);
            update_mass_value_label(app);
        else
            uiwait(msgbox('Please enter a non-zero mass value','Error','modal'))
        end
        end

        % Button pushed function: LoadconfigurationButton
        function LoadconfigurationButtonPushed(app, event)
            direc = sprintf("%s/configuration.mat",app.SelectdirectoryDropDown_2.Value);
            try
                load(direc);
            catch
                uiwait(msgbox('No configuration file found.','Draw Info','modal'))
                return
            end
            app.mass = mass; app.rims = rims; app.N = N; app.R = R; app.rim_no = rim_no;
            figure(1);
            plotStandardButtonPushed(app, event);
            createPlot(app.R,app.N,app.mass,1:app.N);
            
        end

        % Callback function
        function UITableSelectionChanged(app, event)
            % selection = app.UITable.Selection;
            t = readtable("EigenFrequencies.csv");
            vars = {'Mode','Frequency'};
            t = t(:,vars);

            % Add table UI Component
            app.UITable.Data = t;
        end

        % Button pushed function: DisplaycsvButton
        function DisplaycsvButtonPushed(app, event)
            currentdir = cd;
            try
            wd = sprintf("%s\\%s\\%s",currentdir,app.SelectdirectoryDropDown.Value,"EigenFrequencies.csv");
            TableDisplay(wd);
            end
        end

        % Button pushed function: plotStandard_2
        function plotStandard_2ButtonPushed(app, event)
            create_config3(app);
            reset_connections_panel3(app)
            update_mass_selector3(app);
        end

        % Value changed function: SelectamassDropDown_2
        function SelectamassDropDown_2ValueChanged(app, event)
            % value = app.SelectamassDropDown_2.Value;
            try
                app.m1 = str2num(app.SelectamassDropDown_2.Value);
                createPlot3(app.R,app.N,app.mass,app.m1);
    
                mass_connected_text3(app)
    
                update_mass_value_label3(app)
    
                update_mass_position_label3(app, app.m1)
            end
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            remove_matrix_label3(app);
            m2 = str2double(strsplit(app.newConnections_2.Value, ','));
            disp(m2)
            m2 = m2(m2 <= app.N);
            app.mass = addConnection(app.mass, app.m1, m2);

            mass_connected_text3(app)

            createPlot3(app.R,app.N,app.mass,app.m1);
            app.newConnections_2.Value = '';
        end

        % Button pushed function: RemoveButton_2
        function RemoveButton_2Pushed(app, event)
            remove_matrix_label3(app);
            m2 = str2double(strsplit(app.newConnections_2.Value, ','));
            app.mass = removeConnections(app.mass, app.m1, m2);

            % 
            mass_connected_text3(app)

            createPlot3(app.R,app.N,app.mass,app.m1);
            app.newConnections_2.Value = '';
        end

        % Button pushed function: ViewconfigButton_2
        function ViewconfigButton_2Pushed(app, event)
            createPlot3(app.R,app.N,app.mass,1:app.N);
        end

        % Button pushed function: UpdatepositionButton_2
        function UpdatepositionButton_2Pushed(app, event)
            % Change the position
            % Splits the input into x and y
            xyBoxValue = strsplit(app.ChangemasspositionEditField_2.Value,',');
            x = str2double(xyBoxValue(1));
            y = str2double(xyBoxValue(2));
            z = str2double(xyBoxValue(3));
            
            % Fix if we stretch out
            if x > app.R
                app.R = x + 0.5;
            end

            if y > app.R
                app.R = y + 0.5;
            end

            if z > app.R
                app.R = z + 0.5;
            end

            % Changes the positions
            app.mass = change_mass_position3(app.m1,app.mass,x,y,z);
            createPlot3(app.R,app.N,app.mass,1:app.N);

            app.ChangemasspositionEditField_2.Value = '';
            remove_matrix_label3(app);
        end

        % Button pushed function: AddmassButton_2
        function AddmassButton_2Pushed(app, event)
            % Adds a mass
            [app.N, app.mass] = add_mass(app.N,app.mass,true);
            % Updates the selector
            update_mass_selector3(app);
            % Update connections text
            mass_connected_text3(app)
                
            createPlot3(app.R,app.N,app.mass,1:app.N);
            remove_matrix_label3(app);
        end

        % Button pushed function: RemovemassButton_2
        function RemovemassButton_2Pushed(app, event)
            % Removes the mass
            app.mass = remove_mass(app.mass,app.m1,true);
            % Updates the selector, maybe change
            update_mass_selector3(app);
            mass_connected_text3(app);

            createPlot3(app.R,app.N,app.mass,1:app.N);
            remove_matrix_label3(app);
        end

        % Button pushed function: UpdatemassvalueButton_2
        function UpdatemassvalueButton_2Pushed(app, event)
            mass_value = app.NewmassvalueEditField_2.Value;
            if mass_value ~= 0
                app.mass(app.m1).mass_value = mass_value;
                app.NewmassvalueEditField_2.Value = 0;
                remove_matrix_label3(app);
                update_mass_value_label3(app);
            else
                uiwait(msgbox('Please enter a non-zero mass value','Error','modal'))
            end
        end

        % Button pushed function: SetconnectionsButton_2
        function SetconnectionsButton_2Pushed(app, event)
            app.b = [[bxx3(app.N,app.mass) ; bxy3(app.N,app.mass); bxz3(app.N,app.mass)], ...
    [bxy3(app.N,app.mass) ; byy3(app.N,app.mass) ; byz3(app.N,app.mass)], ...
    [bxz3(app.N,app.mass) ; byz3(app.N,app.mass) ; bzz3(app.N,app.mass)]];
            app.b(isnan(app.b))=0;

            app.MatrixcreatedLabel_2.Visible = 'on';
            app.FinaloptionsPanel_2.Visible = 'on';

            % Change mass values
            for j=1:app.N
                app.b(j,:) = app.b(j,:) ./ app.mass(j).mass_value;
                app.b(j+app.N,:) = app.b(j,:) ./ app.mass(j).mass_value;
                app.b(j+2*app.N,:) = app.b(j,:) ./ app.mass(j).mass_value;
            end

            items = "";
            for i = 1:length(app.b)
                % txt = 
                items(i) = sprintf("Mode %i",i);
            end

            app.ModestoplotholdctrlorshifttoselectmultipleListBox_2.Items = items;
            
        end

        % Button pushed function: ViewsparsityButton_2
        function ViewsparsityButton_2Pushed(app, event)
            f2 = figure("Name","Matrix sparcity diagram");
            spy(app.b);
        end

        % Button pushed function: MakeDirectory_2
        function MakeDirectory_2ButtonPushed(app, event)
            if app.CreatedirectoryEditField_2.Value
                val = app.CreatedirectoryEditField_2.Value;
                mkdir(val);
                update_directory(app,true);
                app.SelectdirectoryDropDown_4.Value = val;
                SelectdirectoryDropDown_4Opening(app, event)
            end
        end

        % Button pushed function: CreatemodesButton_2
        function CreatemodesButton_2Pushed(app, event)
            % Get modes that the user wants to plot
            % This is a cell array
            
            listBoxItems = app.ModestoplotholdctrlorshifttoselectmultipleListBox_2.Value;
            modes = zeros(1,length(listBoxItems));
            for i = 1:length(listBoxItems)
                % Split each cell and take integer of what's after the
                % space (i.e mode number)
                temp = strsplit(string(listBoxItems(i)));
                modes(i) = str2double(temp(2));
            end
            
            % Plot and animate modes
            mode_maker3(app.SelectdirectoryDropDown_4.Value,'n','springs',app.b, ...
                app.mass,app.rim_no,app.R,modes);
            app.DisplaymodesPanel_2.Visible = 'on';
            % for j = 1:length(app.b)
            %     items(j) = {sprintf("%i",j)};
            % end
            
            % Update dropdown
            SelectdirectoryDropDown_4Opening(app, event)

            % Save configurationzx
            mass = app.mass; rims = app.rims; N = app.N; R = app.R; rim_no = app.rim_no;
            save_string = sprintf('%s/configuration3.mat',app.SelectdirectoryDropDown_4.Value);
            save(save_string, "mass", "rims", "N", "R", "rim_no"); 
        end

        % Drop down opening function: SelectdirectoryDropDown_4
        function SelectdirectoryDropDown_4Opening(app, event)
            a = dir(app.SelectdirectoryDropDown_4.Value);
            t={''};
            count = 1;
            for j = 3:length(a)
            % if a(j).isdir == 1
                temp = a(j).name;
                t(count) = {temp};
                count = count + 1;
            % end
            end
            app.ModesListBox_2.Items = t;
        end

        % Button pushed function: DisplayGiforImageButton_2
        function DisplayGiforImageButton_2Pushed(app, event)
            currentdir = cd;
            wd = sprintf("%s\\%s\\%s",currentdir,app.SelectdirectoryDropDown_4.Value,app.ModesListBox_2.Value);
            ModeDisplay(wd, app.ModesListBox_2.Value(1:end-4));
        end

        % Button pushed function: DisplaycsvButton_2
        function DisplaycsvButton_2Pushed(app, event)
            currentdir = cd;
            try
                wd = sprintf("%s\\%s\\%s",currentdir,app.SelectdirectoryDropDown_4.Value,"EigenFrequencies.csv");
                TableDisplay(wd);
            end
        end

        % Value changed function: SelectdirectoryDropDown_4
        function SelectdirectoryDropDown_4ValueChanged(app, event)
            SelectdirectoryDropDown_4Opening(app)
        end

        % Value changed function: SelectdirectoryDropDown
        function SelectdirectoryDropDownValueChanged(app, event)
            SelectdirectoryDropDownOpening(app)
        end

        % Button pushed function: LoadconfigurationButton_2
        function LoadconfigurationButton_2Pushed(app, event)
            direc = sprintf("%s/configuration3.mat",app.SelectdirectoryDropDown_3.Value);
            try
                load(direc);
            catch
                uiwait(msgbox('No configuration file found.','Draw Info','modal'))
                return
            end
            app.mass = mass; app.rims = rims; app.N = N; app.R = R; app.rim_no = rim_no;
            figure(1);
            plotStandard_2ButtonPushed(app, event);
            createPlot3(app.R,app.N,app.mass,1:app.N);
        end

        % Selection changed function: ConfigurationButtonGroup_2
        function ConfigurationButtonGroup_2SelectionChanged(app, event)
            % Select configuration
            reset_screen3(app)
            reset_connections_panel3(app)
            selectedButton = app.ConfigurationButtonGroup_2.SelectedObject.Text;
            switch selectedButton
                case 'Tetrahedron'
                    show_standard3(app)
                case 'Octahedron'
                    show_standard3(app)
                case 'Cube'
                    show_standard3(app)
                case 'Import'
                    show_import3(app)
                case 'Custom'
                    show_custom3(app)
            end
            remove_matrix_label3(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '2D';
            app.GridLayout = uigridlayout(app.DTab_2);
            app.MassconfiguratorPanel = uipanel(app.GridLayout);
            app.MassconfiguratorPanel.Title = 'Mass configurator';
            app.massesStandardLabel = uilabel(app.MassconfiguratorPanel);
            app.massesStandardLabel.Visible = 'off';
            app.massesStandardLabel.Text = 'Number of masses';
            app.massesStandard = uieditfield(app.MassconfiguratorPanel, 'numeric');
            app.massesStandard.Visible = 'off';
            app.rimsStandardLabel = uilabel(app.MassconfiguratorPanel);
            app.rimsStandardLabel.Visible = 'off';
            app.rimsStandardLabel.Text = 'Number of rims';
            app.rimsStandard = uieditfield(app.MassconfiguratorPanel, 'numeric');
            app.rimsStandard.Visible = 'off';
            app.plotStandard = uibutton(app.MassconfiguratorPanel, 'push');
            app.plotStandard.ButtonPushedFcn = createCallbackFcn(app, @plotStandardButtonPushed, true);
            app.plotStandard.Visible = 'off';
            app.plotStandard.Text = 'Set configuration';
            app.ConfigurationButtonGroup = uibuttongroup(app.MassconfiguratorPanel);
            app.ConfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfigurationButtonGroupSelectionChanged, true);
            app.ConfigurationButtonGroup.Title = 'Configuration';
            app.StandardButton = uiradiobutton(app.ConfigurationButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.ChebyButton = uiradiobutton(app.ConfigurationButtonGroup);
            app.ChebyButton.Text = 'Cheby';
            app.ImportButton = uiradiobutton(app.ConfigurationButtonGroup);
            app.ImportButton.Text = 'Import';
            app.DrawButton = uiradiobutton(app.ConfigurationButtonGroup);
            app.DrawButton.Text = 'Draw';
            app.SelectdirectoryDropDown_2Label = uilabel(app.MassconfiguratorPanel);
            app.SelectdirectoryDropDown_2Label.Visible = 'off';
            app.SelectdirectoryDropDown_2Label.Text = 'Select directory';
            app.SelectdirectoryDropDown_2 = uidropdown(app.MassconfiguratorPanel);
            app.SelectdirectoryDropDown_2.Items = {};
            app.SelectdirectoryDropDown_2.Visible = 'off';
            app.SelectdirectoryDropDown_2.Value = {};
            app.LoadconfigurationButton = uibutton(app.MassconfiguratorPanel, 'push');
            app.LoadconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadconfigurationButtonPushed, true);
            app.LoadconfigurationButton.Visible = 'off';
            app.LoadconfigurationButton.Text = 'Load configuration';
            app.MassconnectionsPanel = uipanel(app.GridLayout);
            app.MassconnectionsPanel.Title = 'Mass connections';
            app.SelectamassDropDownLabel = uilabel(app.MassconnectionsPanel);
            app.SelectamassDropDownLabel.Text = 'Select a mass';
            app.SelectamassDropDown = uidropdown(app.MassconnectionsPanel);
            app.SelectamassDropDown.Items = {'Select a mass'};
            app.SelectamassDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectamassDropDownOpening, true);
            app.SelectamassDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectamassDropDownValueChanged, true);
            app.SelectamassDropDown.Placeholder = 'Select mass';
            app.SelectamassDropDown.Value = 'Select a mass';
            app.currentConnections = uilabel(app.MassconnectionsPanel);
            app.currentConnections.Text = 'current connections';
            app.ConnectButton = uibutton(app.MassconnectionsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.AddorremoveconnectionscommaseperatedLabel = uilabel(app.MassconnectionsPanel);
            app.AddorremoveconnectionscommaseperatedLabel.Text = 'Add or remove connections comma seperated';
            app.NewconnectionsLabel = uilabel(app.MassconnectionsPanel);
            app.NewconnectionsLabel.Text = 'New connections';
            app.newConnections = uieditfield(app.MassconnectionsPanel, 'text');
            app.newConnections.Placeholder = '1,2,3,4';
            app.ViewconfigButton = uibutton(app.MassconnectionsPanel, 'push');
            app.ViewconfigButton.ButtonPushedFcn = createCallbackFcn(app, @ViewconfigButtonPushed, true);
            app.ViewconfigButton.Text = 'View config';
            app.RemoveButton = uibutton(app.MassconnectionsPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.SetconnectionsButton = uibutton(app.MassconnectionsPanel, 'push');
            app.SetconnectionsButton.ButtonPushedFcn = createCallbackFcn(app, @SetconnectionsButtonPushed, true);
            app.SetconnectionsButton.Text = 'Set connections';
            app.MatrixcreatedLabel = uilabel(app.MassconnectionsPanel);
            app.MatrixcreatedLabel.Visible = 'off';
            app.MatrixcreatedLabel.Text = 'Matrix created';
            app.AddmassButton = uibutton(app.MassconnectionsPanel, 'push');
            app.AddmassButton.ButtonPushedFcn = createCallbackFcn(app, @AddmassButtonPushed, true);
            app.AddmassButton.Text = 'Add mass';
            app.ChangemasspositionEditFieldLabel = uilabel(app.MassconnectionsPanel);
            app.ChangemasspositionEditFieldLabel.Text = 'Change mass position';
            app.ChangemasspositionEditField = uieditfield(app.MassconnectionsPanel, 'text');
            app.ChangemasspositionEditField.Placeholder = 'x,y';
            app.UpdatepositionButton = uibutton(app.MassconnectionsPanel, 'push');
            app.UpdatepositionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatepositionButtonPushed, true);
            app.UpdatepositionButton.Text = 'Update position';
            app.RemovemassButton = uibutton(app.MassconnectionsPanel, 'push');
            app.RemovemassButton.ButtonPushedFcn = createCallbackFcn(app, @RemovemassButtonPushed, true);
            app.RemovemassButton.Text = 'Remove mass';
            app.NewmassvalueEditFieldLabel = uilabel(app.MassconnectionsPanel);
            app.NewmassvalueEditFieldLabel.Text = 'New mass value';
            app.NewmassvalueEditField = uieditfield(app.MassconnectionsPanel, 'numeric');
            app.NewmassvalueEditField.ValueChangedFcn = createCallbackFcn(app, @NewmassvalueEditFieldValueChanged, true);
            app.CurrentmassvalueLabel = uilabel(app.MassconnectionsPanel);
            app.CurrentmassvalueLabel.Text = 'Current mass value: ';
            app.UpdatemassvalueButton = uibutton(app.MassconnectionsPanel, 'push');
            app.UpdatemassvalueButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatemassvalueButtonPushed, true);
            app.UpdatemassvalueButton.Text = 'Update mass value';
            app.position_label = uilabel(app.MassconnectionsPanel);
            app.position_label.Text = '(0,0)';
            app.FinaloptionsPanel = uipanel(app.GridLayout);
            app.FinaloptionsPanel.Title = 'Final options';
            app.ViewsparsityButton = uibutton(app.FinaloptionsPanel, 'push');
            app.ViewsparsityButton.ButtonPushedFcn = createCallbackFcn(app, @ViewsparsityButtonPushed, true);
            app.ViewsparsityButton.Text = 'View sparsity';
            app.SelectdirectoryDropDownLabel = uilabel(app.FinaloptionsPanel);
            app.SelectdirectoryDropDownLabel.Text = 'Select directory';
            app.SelectdirectoryDropDown = uidropdown(app.FinaloptionsPanel);
            app.SelectdirectoryDropDown.Items = {};
            app.SelectdirectoryDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectdirectoryDropDownOpening, true);
            app.SelectdirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectdirectoryDropDownValueChanged, true);
            app.SelectdirectoryDropDown.Value = {};
            app.CreatedirectoryEditFieldLabel = uilabel(app.FinaloptionsPanel);
            app.CreatedirectoryEditFieldLabel.Text = 'Create directory';
            app.CreatedirectoryEditField = uieditfield(app.FinaloptionsPanel, 'text');
            app.MakeDirectory = uibutton(app.FinaloptionsPanel, 'push');
            app.MakeDirectory.ButtonPushedFcn = createCallbackFcn(app, @MakeDirectoryButtonPushed, true);
            app.MakeDirectory.Text = 'Make';
            app.CreatemodesButton = uibutton(app.FinaloptionsPanel, 'push');
            app.CreatemodesButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemodesButtonPushed, true);
            app.CreatemodesButton.Text = 'Create modes';
            app.ModestoplotholdctrlorshifttoselectmultipleLabel = uilabel(app.FinaloptionsPanel);
            app.ModestoplotholdctrlorshifttoselectmultipleLabel.Text = {'Modes to plot'; ''; '(hold ctrl or shift'; 'to select multiple)'};
            app.ModestoplotholdctrlorshifttoselectmultipleListBox = uilistbox(app.FinaloptionsPanel);
            app.ModestoplotholdctrlorshifttoselectmultipleListBox.Items = {};
            app.ModestoplotholdctrlorshifttoselectmultipleListBox.Multiselect = 'on';
            app.ModestoplotholdctrlorshifttoselectmultipleListBox.Value = {};
            app.DisplaymodesPanel = uipanel(app.GridLayout);
            app.DisplaymodesPanel.Title = 'Display modes';
            app.DisplayGiforImageButton = uibutton(app.DisplaymodesPanel, 'push');
            app.DisplayGiforImageButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayGiforImageButtonPushed, true);
            app.DisplayGiforImageButton.Text = 'Display Gif or Image';
            app.ModesListBoxLabel = uilabel(app.DisplaymodesPanel);
            app.ModesListBoxLabel.Text = 'Modes';
            app.ModesListBox = uilistbox(app.DisplaymodesPanel);
            app.ModesListBox.Items = {};
            app.ModesListBox.Value = {};
            app.DisplaycsvButton = uibutton(app.DisplaymodesPanel, 'push');
            app.DisplaycsvButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaycsvButtonPushed, true);
            app.DisplaycsvButton.Text = 'Display csv';
            app.DTab_3 = uitab(app.TabGroup);
            app.DTab_3.Title = '3D';
            app.GridLayout_2 = uigridlayout(app.DTab_3);
            app.MassconfiguratorPanel_2 = uipanel(app.GridLayout_2);
            app.MassconfiguratorPanel_2.Title = 'Mass configurator';
            app.massesStandardLabel_2 = uilabel(app.MassconfiguratorPanel_2);
            app.massesStandardLabel_2.Visible = 'off';
            app.massesStandardLabel_2.Text = 'Number of masses';
            app.massesStandard_2 = uieditfield(app.MassconfiguratorPanel_2, 'numeric');
            app.massesStandard_2.Visible = 'off';
            app.plotStandard_2 = uibutton(app.MassconfiguratorPanel_2, 'push');
            app.plotStandard_2.ButtonPushedFcn = createCallbackFcn(app, @plotStandard_2ButtonPushed, true);
            app.plotStandard_2.Text = 'Set configuration';
            app.SelectdirectoryDropDown_3Label = uilabel(app.MassconfiguratorPanel_2);
            app.SelectdirectoryDropDown_3Label.Visible = 'off';
            app.SelectdirectoryDropDown_3Label.Text = 'Select directory';
            app.SelectdirectoryDropDown_3 = uidropdown(app.MassconfiguratorPanel_2);
            app.SelectdirectoryDropDown_3.Items = {};
            app.SelectdirectoryDropDown_3.Visible = 'off';
            app.SelectdirectoryDropDown_3.Value = {};
            app.LoadconfigurationButton_2 = uibutton(app.MassconfiguratorPanel_2, 'push');
            app.LoadconfigurationButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadconfigurationButton_2Pushed, true);
            app.LoadconfigurationButton_2.Visible = 'off';
            app.LoadconfigurationButton_2.Text = 'Load configuration';
            app.ConfigurationButtonGroup_2 = uibuttongroup(app.MassconfiguratorPanel_2);
            app.ConfigurationButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ConfigurationButtonGroup_2SelectionChanged, true);
            app.ConfigurationButtonGroup_2.Title = 'Configuration';
            app.TetrahedronButton = uiradiobutton(app.ConfigurationButtonGroup_2);
            app.TetrahedronButton.Text = 'Tetrahedron';
            app.TetrahedronButton.Value = true;
            app.CubeButton = uiradiobutton(app.ConfigurationButtonGroup_2);
            app.CubeButton.Text = 'Cube';
            app.OctahedronButton = uiradiobutton(app.ConfigurationButtonGroup_2);
            app.OctahedronButton.Text = 'Octahedron';
            app.ImportButton_2 = uiradiobutton(app.ConfigurationButtonGroup_2);
            app.ImportButton_2.Text = 'Import';
            app.CustomButton = uiradiobutton(app.ConfigurationButtonGroup_2);
            app.CustomButton.Text = 'Custom';
            app.MassconnectionsPanel_2 = uipanel(app.GridLayout_2);
            app.MassconnectionsPanel_2.Title = 'Mass connections';
            app.SelectamassDropDown_2Label = uilabel(app.MassconnectionsPanel_2);
            app.SelectamassDropDown_2Label.Text = 'Select a mass';
            app.SelectamassDropDown_2 = uidropdown(app.MassconnectionsPanel_2);
            app.SelectamassDropDown_2.Items = {'Select a mass'};
            app.SelectamassDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectamassDropDown_2ValueChanged, true);
            app.SelectamassDropDown_2.Placeholder = 'Select mass';
            app.SelectamassDropDown_2.Value = 'Select a mass';
            app.currentConnections_2 = uilabel(app.MassconnectionsPanel_2);
            app.currentConnections_2.Text = 'current connections';
            app.ConnectButton_2 = uibutton(app.MassconnectionsPanel_2, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.AddorremoveconnectionscommaseperatedLabel_2 = uilabel(app.MassconnectionsPanel_2);
            app.AddorremoveconnectionscommaseperatedLabel_2.Text = 'Add or remove connections comma seperated';
            app.NewconnectionsLabel_2 = uilabel(app.MassconnectionsPanel_2);
            app.NewconnectionsLabel_2.Text = 'New connections';
            app.newConnections_2 = uieditfield(app.MassconnectionsPanel_2, 'text');
            app.newConnections_2.Placeholder = '1,2,3,4';
            app.ViewconfigButton_2 = uibutton(app.MassconnectionsPanel_2, 'push');
            app.ViewconfigButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewconfigButton_2Pushed, true);
            app.ViewconfigButton_2.Text = 'View config';
            app.RemoveButton_2 = uibutton(app.MassconnectionsPanel_2, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_2Pushed, true);
            app.RemoveButton_2.Text = 'Remove';
            app.SetconnectionsButton_2 = uibutton(app.MassconnectionsPanel_2, 'push');
            app.SetconnectionsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetconnectionsButton_2Pushed, true);
            app.SetconnectionsButton_2.Text = 'Set connections';
            app.MatrixcreatedLabel_2 = uilabel(app.MassconnectionsPanel_2);
            app.MatrixcreatedLabel_2.Visible = 'off';
            app.MatrixcreatedLabel_2.Text = 'Matrix created';
            app.AddmassButton_2 = uibutton(app.MassconnectionsPanel_2, 'push');
            app.AddmassButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddmassButton_2Pushed, true);
            app.AddmassButton_2.Text = 'Add mass';
            app.ChangemasspositionEditField_2Label = uilabel(app.MassconnectionsPanel_2);
            app.ChangemasspositionEditField_2Label.Text = 'Change mass position';
            app.ChangemasspositionEditField_2 = uieditfield(app.MassconnectionsPanel_2, 'text');
            app.ChangemasspositionEditField_2.Placeholder = 'x,y,z';
            app.UpdatepositionButton_2 = uibutton(app.MassconnectionsPanel_2, 'push');
            app.UpdatepositionButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdatepositionButton_2Pushed, true);
            app.UpdatepositionButton_2.Text = 'Update position';
            app.RemovemassButton_2 = uibutton(app.MassconnectionsPanel_2, 'push');
            app.RemovemassButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemovemassButton_2Pushed, true);
            app.RemovemassButton_2.Text = 'Remove mass';
            app.NewmassvalueEditField_2Label = uilabel(app.MassconnectionsPanel_2);
            app.NewmassvalueEditField_2Label.Text = 'New mass value';
            app.NewmassvalueEditField_2 = uieditfield(app.MassconnectionsPanel_2, 'numeric');
            app.CurrentmassvalueLabel_2 = uilabel(app.MassconnectionsPanel_2);
            app.CurrentmassvalueLabel_2.Text = 'Current mass value: ';
            app.UpdatemassvalueButton_2 = uibutton(app.MassconnectionsPanel_2, 'push');
            app.UpdatemassvalueButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdatemassvalueButton_2Pushed, true);
            app.UpdatemassvalueButton_2.Text = 'Update mass value';
            app.position_label_2 = uilabel(app.MassconnectionsPanel_2);
            app.position_label_2.Text = '(0,0,0)';
            app.FinaloptionsPanel_2 = uipanel(app.GridLayout_2);
            app.FinaloptionsPanel_2.Title = 'Final options';
            app.ViewsparsityButton_2 = uibutton(app.FinaloptionsPanel_2, 'push');
            app.ViewsparsityButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewsparsityButton_2Pushed, true);
            app.ViewsparsityButton_2.Text = 'View sparsity';
            app.SelectdirectoryDropDown_4Label = uilabel(app.FinaloptionsPanel_2);
            app.SelectdirectoryDropDown_4Label.Text = 'Select directory';
            app.SelectdirectoryDropDown_4 = uidropdown(app.FinaloptionsPanel_2);
            app.SelectdirectoryDropDown_4.Items = {};
            app.SelectdirectoryDropDown_4.DropDownOpeningFcn = createCallbackFcn(app, @SelectdirectoryDropDown_4Opening, true);
            app.SelectdirectoryDropDown_4.ValueChangedFcn = createCallbackFcn(app, @SelectdirectoryDropDown_4ValueChanged, true);
            app.SelectdirectoryDropDown_4.Value = {};
            app.CreatedirectoryEditField_2Label = uilabel(app.FinaloptionsPanel_2);
            app.CreatedirectoryEditField_2Label.Text = 'Create directory';
            app.CreatedirectoryEditField_2 = uieditfield(app.FinaloptionsPanel_2, 'text');
            app.MakeDirectory_2 = uibutton(app.FinaloptionsPanel_2, 'push');
            app.MakeDirectory_2.ButtonPushedFcn = createCallbackFcn(app, @MakeDirectory_2ButtonPushed, true);
            app.MakeDirectory_2.Text = 'Make';
            app.CreatemodesButton_2 = uibutton(app.FinaloptionsPanel_2, 'push');
            app.CreatemodesButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreatemodesButton_2Pushed, true);
            app.CreatemodesButton_2.Text = 'Create modes';
            app.ModestoplotholdctrlorshifttoselectmultipleListBox_2Label = uilabel(app.FinaloptionsPanel_2);
            app.ModestoplotholdctrlorshifttoselectmultipleListBox_2Label.Text = {'Modes to plot'; ''; '(hold ctrl or shift'; 'to select multiple)'};
            app.ModestoplotholdctrlorshifttoselectmultipleListBox_2 = uilistbox(app.FinaloptionsPanel_2);
            app.ModestoplotholdctrlorshifttoselectmultipleListBox_2.Items = {};
            app.ModestoplotholdctrlorshifttoselectmultipleListBox_2.Multiselect = 'on';
            app.ModestoplotholdctrlorshifttoselectmultipleListBox_2.Value = {};
            app.DisplaymodesPanel_2 = uipanel(app.GridLayout_2);
            app.DisplaymodesPanel_2.Title = 'Display modes';
            app.DisplayGiforImageButton_2 = uibutton(app.DisplaymodesPanel_2, 'push');
            app.DisplayGiforImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisplayGiforImageButton_2Pushed, true);
            app.DisplayGiforImageButton_2.Text = 'Display Gif or Image';
            app.ModesListBox_2Label = uilabel(app.DisplaymodesPanel_2);
            app.ModesListBox_2Label.Text = 'Modes';
            app.ModesListBox_2 = uilistbox(app.DisplaymodesPanel_2);
            app.ModesListBox_2.Items = {};
            app.ModesListBox_2.Value = {};
            app.DisplaycsvButton_2 = uibutton(app.DisplaymodesPanel_2, 'push');
            app.DisplaycsvButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisplaycsvButton_2Pushed, true);
            app.DisplaycsvButton_2.Text = 'Display csv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wheelmakerapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end